classdef dockRepoFiles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        Panel               matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        CHAMAREVENTOButton  matlab.ui.control.Button
        DropDown            matlab.ui.control.DropDown
        DropDownLabel       matlab.ui.control.Label
        UITable             matlab.ui.control.Table
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockApp'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = true        
        mainApp
        callingApp
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        inputArgs
    end
    
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function updatePanel(app)
            % ...
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, context)
            
            try
                appEngine.boot(app, app.Role, mainApp, callingApp)

                app.inputArgs = struct('context', context);
                updatePanel(app)
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Button pushed function: CHAMAREVENTOButton
        function CHAMAREVENTOButtonPushed(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'NomeEventoEspecifico')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.DropDownLabel = uilabel(app.GridLayout2);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.GridLayout2);
            app.CHAMAREVENTOButton = uibutton(app.GridLayout2, 'push');
            app.CHAMAREVENTOButton.ButtonPushedFcn = createCallbackFcn(app, @CHAMAREVENTOButtonPushed, true);
            app.CHAMAREVENTOButton.Text = 'CHAMAR EVENTO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockRepoFiles(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end